classdef conTPL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        DefaultTab                  matlab.ui.container.Tab
        BackwardLabel               matlab.ui.control.Label
        ForwardLabel                matlab.ui.control.Label
        TMDropDownLabel_backward    matlab.ui.control.Label
        TMDropDown_backward         matlab.ui.control.DropDown
        TMDropDownLabel_forward     matlab.ui.control.Label
        TMDropDown_forward          matlab.ui.control.DropDown
        EpsilonattEditFieldLabel    matlab.ui.control.Label
        EpsilonattEditField         matlab.ui.control.NumericEditField
        BackwardButton              matlab.ui.control.StateButton
        NexttButton                 matlab.ui.control.Button
        Runto20Button               matlab.ui.control.Button
        TPLButton                   matlab.ui.control.Button
        CustomizedTab               matlab.ui.container.Tab
        SEditFieldLabel             matlab.ui.control.Label
        SEditField                  matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
        EpsilonattEditField_2Label  matlab.ui.control.Label
        EpsilonattEditField_2       matlab.ui.control.NumericEditField
        SwitchLabel                 matlab.ui.control.Label
        SwitchBackwardForward       matlab.ui.control.Switch
        NextTimeButton              matlab.ui.control.Button
        Runto20sButton              matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        AllPlotsButton              matlab.ui.control.Button
        ClearAllButton              matlab.ui.control.Button
    end


    properties (Access = public)
        dim_n = 50; % Description
        TM = [0.8,0.2; 0,1];
        eps = 0.1;
        PL = 0;
        time = 20;
        
        % action on 'Next t' button: step by step
        PLSteps = 0; 
        timeSteps = 0;
        BPLSteps = [];
        
        isBPL = 1;
        initS = 0;
        
        %customized tab
        edtS = 0;
        edtN = 50;
        cusEps = 0.1;
        cusTM = [0.8,0.2; 0,1];
        cusPL = 0;
        cusTime = 20;
        cusIsBPL = 1;
        cusPLSteps = 0; 
        cusTimeSteps = 0;
        cusBPLSteps = [];
        
    end

    properties (Access = private)
        libpath = addpath('tools/'); % Description
        
    end

    methods (Access = public)
    
        function [res_TM] = genTM(app, s, n)
            res_TM = diag(ones(n,1))+s;
            res_TM = res_TM+s;
            res_TM = res_TM./sum(res_TM,2);
            
        end


    end

    methods (Access = private)
    
        function [newPL]=calcPL(app, pl, e, EspMatrix, qM, dM)
            % calc by precomputation
            [incrementalPL, val1, val2] = calcPLbyPreComp(pl, EspMatrix, qM, dM);
            newPL = incrementalPL+ e;
        end
        
        function resetValues(app)
            app.BPLSteps = [];
            app.timeSteps = 0;
            app.PLSteps = 0;
        end
        
    end


    methods (Access = private)

        % Value changed function: TMDropDown_backward
        function TMDropDown_backwardValueChanged(app, event)
            
            value = app.TMDropDown_backward.Value;
%             disp(value);
            app.TM = genTM(app,str2double(value),app.dim_n);
            resetValues(app);
%             app.BPLSteps = [];
%             app.timeSteps = 0;
%             app.PLSteps = 0;
            % Enable 'Next' button and 'Run to 20' button
            app.Runto20Button.Enable = 'on';
            app.NexttButton.Enable = 'on';
            
%             disp(app.TM);
            
        end

        % Value changed function: TMDropDown_forward
        function TMDropDown_forwardValueChanged(app, event)
            
            value = app.TMDropDown_forward.Value;
%             disp(value);
            app.TM = genTM(app,str2double(value),app.dim_n);
            resetValues(app);
%             app.BPLSteps = [];
%             app.timeSteps = 0;
%             app.PLSteps = 0;
            % Enable 'Next' button and 'Run to 20' button
            app.Runto20Button.Enable = 'on';
            app.NexttButton.Enable = 'on';
            
        end

        % Value changed function: EpsilonattEditField
        function EpsilonattEditFieldValueChanged(app, event)
            value = app.EpsilonattEditField.Value;
            app.eps = value;
%             disp(value);
        end

        % Button pushed function: Runto20Button
        function Runto20ButtonPushed(app, event)
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(app.TM);
            bpls = [];
            app.PL = 0;
%             disp('isBPL:');
%             disp(app.isBPL);
            
            for t = 1:app.time
                app.PL = calcPL(app, app.PL, app.eps, EspMatrix, qM, dM);
                bpls = [bpls app.PL];
            end
            
            if app.isBPL == 0
                bpls = fliplr(bpls);
            end
%             disp('after');
%             disp(bpls);
            
%             app.UIAxes.XTick = times;
            times = 1:1:app.time;
            
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, times, bpls,'-o');
        end

        % Value changed function: BackwardButton
        function BackwardButtonValueChanged(app, event)
            value = app.BackwardButton.Value;
            % Enable 'Next' button and 'Run to 20' button
            app.Runto20Button.Enable = 'on';
            app.NexttButton.Enable = 'on';
            switch value
                case 0
                    app.BackwardButton.Text = 'Backward';
                    app.TMDropDownLabel_backward.Enable = 'on';
                    app.TMDropDown_backward.Enable = 'on';
                    app.TMDropDownLabel_forward.Enable = 'off';
                    app.TMDropDown_forward.Enable = 'off';
                    app.isBPL = 1;
                    resetValues(app);
%                     app.BPLSteps = [];
%                     app.timeSteps = 0;
%                     app.PLSteps = 0;
%                     app.TM = genTM(app,app.initS,app.dim_n);
                case 1
                    app.BackwardButton.Text = 'Forward';
                    app.TMDropDownLabel_backward.Enable = 'off';
                    app.TMDropDown_backward.Enable = 'off';
                    app.TMDropDownLabel_forward.Enable = 'on';
                    app.TMDropDown_forward.Enable = 'on';
                    app.isBPL = 0;
                    resetValues(app);
%                     app.BPLSteps = [];
%                     app.timeSteps = 0;
%                     app.PLSteps = 0;
%                     app.TM = genTM(app,app.initS,app.dim_n);
            end
%             disp(value);
        end

        % Button pushed function: NexttButton
        function NexttButtonPushed(app, event)
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(app.TM);
            bpls = [];
            app.timeSteps = app.timeSteps + 1;
            app.PLSteps = calcPL(app, app.PLSteps, app.eps, EspMatrix, qM, dM);
            app.BPLSteps = [app.BPLSteps app.PLSteps];
            
            disp('Before BPLSteps=');
            disp(app.BPLSteps);
            bpls = app.BPLSteps;
            
            disp('Before bpls = ');
            disp(bpls);
            if app.isBPL == 0
                bpls = fliplr(bpls);
            end
%             disp('after');
%             disp(bpls);
            
%             app.UIAxes.XTick = times;
            times = 1:1:app.timeSteps;
            disp('times = ');
            disp(times);
            
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, times, bpls,'-*');
        end

        % Button pushed function: NextTimeButton
        function NextTimeButtonPushed(app, event)
            app.cusTM = genTM(app,app.edtS,app.edtN);
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(app.cusTM);
            bpls = [];
            app.cusTimeSteps = app.cusTimeSteps + 1;
            app.cusPLSteps = calcPL(app, app.cusPLSteps, app.cusEps, EspMatrix, qM, dM);
            app.cusBPLSteps = [app.cusBPLSteps app.cusPLSteps];
            
            disp('Before cusBPLSteps=');
            disp(app.cusBPLSteps);
            bpls = app.cusBPLSteps;
            
            disp('Before bpls = ');
            disp(bpls);
            if app.cusIsBPL == 0
                bpls = fliplr(bpls);
            end
%             disp('after');
%             disp(bpls);
            
%             app.UIAxes.XTick = times;
            times = 1:1:app.cusTimeSteps;
            disp('times = ');
            disp(times);
            
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, times, bpls,'-*');
        end

        % Button pushed function: Runto20sButton
        function Runto20sButtonPushed(app, event)
            app.cusTM = genTM(app,app.edtS,app.edtN);
            disp('cusTM');
            disp(app.cusTM);
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(app.cusTM);
            bpls = [];
            app.cusPL = 0;
%             disp('isBPL:');
%             disp(app.isBPL);
            
            for t = 1:app.cusTime
                app.cusPL = calcPL(app, app.cusPL, app.cusEps, EspMatrix, qM, dM);
                bpls = [bpls app.cusPL];
            end
            
            if app.cusIsBPL == 0
                bpls = fliplr(bpls);
            end
%             disp('after');
%             disp(bpls);
            
%             app.UIAxes.XTick = times;
            times = 1:1:app.time;
            
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, times, bpls,'-o');
        end

        % Value changed function: SwitchBackwardForward
        function SwitchBackwardForwardValueChanged(app, event)
            value = app.SwitchBackwardForward.Value;
            disp('switch value:');
            disp(value);
            switch value
                case 'Forward'
                    app.cusIsBPL = 0;
                case 'Backward'
                    app.cusIsBPL = 1;
            end
            
        end

        % Value changed function: EpsilonattEditField_2
        function EpsilonattEditField_2ValueChanged(app, event)
            value = app.EpsilonattEditField_2.Value;
            app.cusEps = value;
            disp('app.cusEps');
            disp(app.cusEps);
        end

        % Value changed function: SEditField
        function SEditFieldValueChanged(app, event)
            value = app.SEditField.Value;
            app.edtS = value;
            disp('app.edtS');
            disp(app.edtS);
            
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.edtN = value;
            disp('edtN');
            disp(app.edtN);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.cusBPLSteps = [];
            app.cusTimeSteps = 0;
            app.cusPLSteps = 0;
            cla(app.UIAxes);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.dim_n = 50; % Description
            app.TM = [0.8,0.2; 0,1];
            app.eps = 0.1;
            app.PL = 0;
            app.time = 20;
            
            % action on 'Next t' button: step by step
            app.PLSteps = 0; 
            app.timeSteps = 0;
            app.BPLSteps = [];
            
            app.isBPL = 1;
            app.initS = 0;
            
            %customized tab
            app.edtS = 0;
            app.edtN = 50;
            app.cusEps = 0.1;
            app.cusTM = [0.8,0.2; 0,1];
            app.cusPL = 0;
            app.cusTime = 20;
            app.cusIsBPL = 1;
            app.cusPLSteps = 0; 
            app.cusTimeSteps = 0;
            app.cusBPLSteps = [];
            cla(app.UIAxes);
        end

        % Button pushed function: TPLButton
        function TPLButtonPushed(app, event)
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(app.TM);
            bpls = [];
            fpls = [];
            tpl = 0;
            tpls = [];
            app.PL = 0;
%             disp('isBPL:');
%             disp(app.isBPL);
            
            for t = 1:app.time
                app.PL = calcPL(app, app.PL, app.eps, EspMatrix, qM, dM);
                bpls = [bpls app.PL];
            end
            
            fpls = fliplr(bpls);
            
            for cnt = 1:app.time
                tpl = bpls(cnt) + fpls(cnt) - app.eps;
                disp('tpl = ');
                disp(tpl);
                tpls = [tpls tpl];
            end
            disp('tpls = ');
            disp(tpls);
            
%             disp('after');
%             disp(bpls);
            
%             app.UIAxes.XTick = times;
            times = 1:1:app.time;
            
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, times, bpls,'-o');
            hold(app.UIAxes);
            plot(app.UIAxes, times, fpls,'-*');
%             hold(app.UIAxes);
            plot(app.UIAxes, times, tpls,'-^');
            
        end

        % Button pushed function: AllPlotsButton
        function AllPlotsButtonPushed(app, event)
            app.cusTM = genTM(app,app.edtS,app.edtN);
            disp('cusTM');
            disp(app.cusTM);
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(app.cusTM);
            bpls = [];
            fpls = [];
            tpl = 0;
            tpls = [];
            app.cusPL = 0;
%             disp('isBPL:');
%             disp(app.isBPL);
            
            for t = 1:app.cusTime
                app.cusPL = calcPL(app, app.cusPL, app.cusEps, EspMatrix, qM, dM);
                bpls = [bpls app.cusPL];
            end
            
            fpls = fliplr(bpls);
            
            for cnt = 1:app.cusTime
                tpl = bpls(cnt) + fpls(cnt) - app.cusEps;
                tpls = [tpls tpl];
            end
            
%             disp('after');
%             disp(bpls);
            
%             app.UIAxes.XTick = times;
            times = 1:1:app.time;
            
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, times, bpls,'-o');
            hold(app.UIAxes);
            plot(app.UIAxes, times, fpls,'-*');
            plot(app.UIAxes, times, tpls,'-^');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Privacy Loss')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DefaultTab = uitab(app.TabGroup);
            app.DefaultTab.Title = 'Default';
            app.BackwardLabel = uilabel(app.DefaultTab);
            app.BackwardLabel.Text = 'Backward';
            app.ForwardLabel = uilabel(app.DefaultTab);
            app.ForwardLabel.Text = 'Forward';
            app.TMDropDownLabel_backward = uilabel(app.DefaultTab);
            app.TMDropDownLabel_backward.Text = 'TM';
            app.TMDropDown_backward = uidropdown(app.DefaultTab);
            app.TMDropDown_backward.Items = {'TM1', 'TM2', 'TM3'};
            app.TMDropDown_backward.ItemsData = {'0', '0.005', '0.05'};
            app.TMDropDown_backward.ValueChangedFcn = createCallbackFcn(app, @TMDropDown_backwardValueChanged, true);
            app.TMDropDown_backward.Value = '0';
            app.TMDropDownLabel_forward = uilabel(app.DefaultTab);
            app.TMDropDownLabel_forward.Enable = 'off';
            app.TMDropDownLabel_forward.Text = 'TM';
            app.TMDropDown_forward = uidropdown(app.DefaultTab);
            app.TMDropDown_forward.Items = {'TM1', 'TM2', 'TM3'};
            app.TMDropDown_forward.ItemsData = {'0', '0.005', '0.05'};
            app.TMDropDown_forward.ValueChangedFcn = createCallbackFcn(app, @TMDropDown_forwardValueChanged, true);
            app.TMDropDown_forward.Enable = 'off';
            app.TMDropDown_forward.Value = '0';
            app.EpsilonattEditFieldLabel = uilabel(app.DefaultTab);
            app.EpsilonattEditFieldLabel.Text = 'Epsilon at t';
            app.EpsilonattEditField = uieditfield(app.DefaultTab, 'numeric');
            app.EpsilonattEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonattEditFieldValueChanged, true);
            app.EpsilonattEditField.Value = 0.1;
            app.BackwardButton = uibutton(app.DefaultTab, 'state');
            app.BackwardButton.ValueChangedFcn = createCallbackFcn(app, @BackwardButtonValueChanged, true);
            app.BackwardButton.Text = 'Backward';
            app.NexttButton = uibutton(app.DefaultTab, 'push');
            app.NexttButton.ButtonPushedFcn = createCallbackFcn(app, @NexttButtonPushed, true);
            app.NexttButton.Enable = 'off';
            app.NexttButton.Text = 'Next t';
            app.Runto20Button = uibutton(app.DefaultTab, 'push');
            app.Runto20Button.ButtonPushedFcn = createCallbackFcn(app, @Runto20ButtonPushed, true);
            app.Runto20Button.Enable = 'off';
            app.Runto20Button.Text = 'Run to 20';
            app.TPLButton = uibutton(app.DefaultTab, 'push');
            app.TPLButton.ButtonPushedFcn = createCallbackFcn(app, @TPLButtonPushed, true);
            app.TPLButton.Text = 'TPL';
            app.CustomizedTab = uitab(app.TabGroup);
            app.CustomizedTab.Title = 'Customized';
            app.SEditFieldLabel = uilabel(app.CustomizedTab);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.CustomizedTab, 'numeric');
            app.SEditField.ValueChangedFcn = createCallbackFcn(app, @SEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.CustomizedTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.CustomizedTab, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 50;
            app.EpsilonattEditField_2Label = uilabel(app.CustomizedTab);
            app.EpsilonattEditField_2Label.Text = 'Epsilon at t';
            app.EpsilonattEditField_2 = uieditfield(app.CustomizedTab, 'numeric');
            app.EpsilonattEditField_2.Limits = [0 Inf];
            app.EpsilonattEditField_2.ValueChangedFcn = createCallbackFcn(app, @EpsilonattEditField_2ValueChanged, true);
            app.SwitchLabel = uilabel(app.CustomizedTab);
            app.SwitchLabel.Text = 'Switch';
            app.SwitchBackwardForward = uiswitch(app.CustomizedTab, 'slider');
            app.SwitchBackwardForward.Items = {'Backward', 'Forward'};
            app.SwitchBackwardForward.ValueChangedFcn = createCallbackFcn(app, @SwitchBackwardForwardValueChanged, true);
            app.SwitchBackwardForward.Value = 'Backward';
            app.NextTimeButton = uibutton(app.CustomizedTab, 'push');
            app.NextTimeButton.ButtonPushedFcn = createCallbackFcn(app, @NextTimeButtonPushed, true);
            app.NextTimeButton.Text = 'Next Time';
            app.Runto20sButton = uibutton(app.CustomizedTab, 'push');
            app.Runto20sButton.ButtonPushedFcn = createCallbackFcn(app, @Runto20sButtonPushed, true);
            app.Runto20sButton.Text = 'Run to 20s';
            app.ResetButton = uibutton(app.CustomizedTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AllPlotsButton = uibutton(app.CustomizedTab, 'push');
            app.AllPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @AllPlotsButtonPushed, true);
            app.AllPlotsButton.Text = 'All Plots';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = conTPL

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end